classdef linecablelab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainform                        matlab.ui.Figure
        ConductordatabaseButton         matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        LoadinputsessionButton          matlab.ui.control.Button
        SaveinputsessionButton          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CasedataTab                     matlab.ui.container.Tab
        RefreshPreview                  matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        ProjectdescriptionTextArea      matlab.ui.control.TextArea
        ProjectdescriptionLabel         matlab.ui.control.Label
        JobIDEditField                  matlab.ui.control.EditField
        JobIDLabel                      matlab.ui.control.Label
        WorkdirectoryEditField          matlab.ui.control.EditField
        WorkdirectoryEditFieldLabel     matlab.ui.control.Label
        LoadWorkdirButton               matlab.ui.control.Button
        OHLcrosssectionconductordataPanel  matlab.ui.container.Panel
        OHLTlengthmEditField            matlab.ui.control.NumericEditField
        OHLTlengthmEditFieldLabel       matlab.ui.control.Label
        row2Delete                      matlab.ui.control.NumericEditField
        CurrentrowLabel                 matlab.ui.control.Label
        NotesetPhaseNum0toenforceKroneliminationLabel  matlab.ui.control.Label
        DeleterowButton                 matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        ComputationsTab                 matlab.ui.container.Tab
        FittingoptionsPanel             matlab.ui.container.Panel
        MinPolesEditField               matlab.ui.control.NumericEditField
        MinPolesEditFieldLabel          matlab.ui.control.Label
        VFTOLpuEditField                matlab.ui.control.NumericEditField
        VFTOLLabel                      matlab.ui.control.Label
        BFTOLdBEditField                matlab.ui.control.NumericEditField
        BFTOLdBEditFieldLabel           matlab.ui.control.Label
        MaxPolesEditField               matlab.ui.control.NumericEditField
        MaxPolesEditFieldLabel          matlab.ui.control.Label
        ExportlinemodelPanel            matlab.ui.container.Panel
        ATPDrawcompliantXMLfileCheckBox  matlab.ui.control.CheckBox
        ATPTYPE94fitULMmodelDeContietalCheckBox  matlab.ui.control.CheckBox
        ATPcompliantPCHJMartimodelCheckBox  matlab.ui.control.CheckBox
        EMTPcompliantLineCable_DataMATfileCheckBox  matlab.ui.control.CheckBox
        UniformbottomsoillayerPanel     matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        PermittivitypuEditField         matlab.ui.control.NumericEditField
        PermittivitypuEditFieldLabel    matlab.ui.control.Label
        PermeabilitypuEditField         matlab.ui.control.NumericEditField
        PermeabilitypuEditFieldLabel    matlab.ui.control.Label
        ResistivitymEditField           matlab.ui.control.NumericEditField
        ResistivitymEditFieldLabel      matlab.ui.control.Label
        Layer2Delete                    matlab.ui.control.NumericEditField
        CurrentrowLabel_2               matlab.ui.control.Label
        TopsoillayersLabel              matlab.ui.control.Label
        MiscPanel                       matlab.ui.container.Panel
        NotesetPhaseNum0toenforceKroneliminationLabel_2  matlab.ui.control.Label
        ExtFileEditField                matlab.ui.control.EditField
        ExtFileEditFieldLabel           matlab.ui.control.Label
        LoadExtFileButton               matlab.ui.control.Button
        PythonPathEditField             matlab.ui.control.EditField
        PythonPathEditFieldLabel        matlab.ui.control.Label
        LoadPythonPathButton            matlab.ui.control.Button
        PlotsavePanel                   matlab.ui.container.Panel
        RequestpulparametersplotsCheckBox  matlab.ui.control.CheckBox
        SavedatatoMATfilesCheckBox      matlab.ui.control.CheckBox
        RequestmodalparametersplotsCheckBox  matlab.ui.control.CheckBox
        UITableLayers                   matlab.ui.control.Table
        DeleteButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        FrequencysweeplogspacedpointsPanel  matlab.ui.container.Panel
        Lowerdec10LHzSpinner            matlab.ui.control.Spinner
        Lowerdec10LHzSpinnerLabel       matlab.ui.control.Label
        PointsperdecadeSpinner          matlab.ui.control.Spinner
        PointsperdecadeSpinnerLabel     matlab.ui.control.Label
        Upperdec10UHzSpinner            matlab.ui.control.Spinner
        Upperdec10UHzSpinnerLabel       matlab.ui.control.Label
        ModaltransformPanel             matlab.ui.container.Panel
        ModaldecompositionflagDropDown  matlab.ui.control.DropDown
        ModaldecompositionflagDropDownLabel  matlab.ui.control.Label
        ModaltransformmatrixDropDown    matlab.ui.control.DropDown
        ModaltransformmatrixDropDownLabel  matlab.ui.control.Label
        GroundreturnmodelPanel          matlab.ui.container.Panel
        FDsoilDropDown                  matlab.ui.control.DropDown
        FDsoilDropDownLabel             matlab.ui.control.Label
        AdmittanceDropDown              matlab.ui.control.DropDown
        AdmittanceDropDownLabel         matlab.ui.control.Label
        ImpedanceDropDown               matlab.ui.control.DropDown
        ImpedanceDropDownLabel          matlab.ui.control.Label
    end


    methods (Access = private)

        function [isgood, msg] = validateWorkdir(app)
            fname=app.WorkdirectoryEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'dir')
                msg='Invalid work directory!';
                isgood=false;
                return;
            end
        end


        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);
        end

        function out = redefLayerLabels(app)
            nrows = size(app.UITableLayers.Data,1);
            app.UITableLayers.RowName=string(1:nrows);
            app.UITableLayers.ColumnFormat = {'numeric'};
            app.UITableLayers.ColumnFormat = repmat( {'numeric'}, 1, 4 );

%             % Total table width (excluding vertical scrollbar)
%             totalWidth = 503 - 50;  
% 
%             % Number of columns (excluding the row names)
%             numColumns = 4;
% 
%             % Calculate the width for each column
%             equalColumnWidth = floor(totalWidth / numColumns);  % Using floor to avoid decimal pixel values
% 
%             % Create a cell array to set the 'ColumnWidth' property
%             columnWidths = repmat({equalColumnWidth}, 1, numColumns);  % A cell array with equal widths
% 
%             % Apply the column widths
%             app.UITableLayers.ColumnWidth = columnWidths;

        end

        function out = addNewLayer(app)
            newrow={100, 1, 1, 1};
            app.UITableLayers.Data=[app.UITableLayers.Data; newrow];
            redefLayerLabels(app);
        end
        
        function results = saveAllData(app)

            results=false;
            %[testdir,msg]=validateWorkDir(app);
            %if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            %end

            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=[projID '.mat'];
                ohlt_data = readAllAppValues(app);
                save(fullfile(projdir,fname),'ohlt_data');
                %             warning(orig_state);
                results=true;
            end
        end

        function results = writeAllAppValues(app,ohlt_data)

            props = properties(app);
            
            try
                for iprop = 1:length(props)
                    thisprop = props{iprop};

                    if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                            strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                            strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                            strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')
                        
                        if isprop(app.(thisprop),'Items')
                            app.(thisprop).Items=ohlt_data.(thisprop).Items;
                        end

                        if isprop(app.(thisprop),'Value')
                            app.(thisprop).Value=ohlt_data.(thisprop).Value;
                        end

                        app.(thisprop).Enable=ohlt_data.(thisprop).Enable;
                        app.(thisprop).Visible=ohlt_data.(thisprop).Visible;

                        if isprop(app.(thisprop),'Editable')
                            app.(thisprop).Editable=ohlt_data.(thisprop).Editable;
                        end

                        if isprop(app.(thisprop),'List')
                            app.(thisprop).List=ohlt_data.(thisprop).List;
                        end

                        if isprop(app.(thisprop),'Data')
                            app.(thisprop).Data=ohlt_data.(thisprop).Data;
                        end

                    end

                end
            catch
                % do nothing, jon snow
            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Items')
                        appdata.(thisprop).Items=app.(thisprop).Items;
                    end


                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end

        function [isgood, msg] = validateJobID(app)
            jobID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(jobID)
                isgood=false;
                msg='You must specify a JobID!';
                return;
            else
                bad = ismember(BadChar, jobID);
                if any(bad)
                    msg = ['JobID name contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(jobID < 32)
                    msg = ['JobID name contains non printable characters, ASCII:', sprintf(' %d', jobID(jobID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(jobID) && (jobID(end) == ' ' || jobID(end) == '.')
                    msg = 'JobID name must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(jobID);
                    if any(strcmpi(name, BadName))
                        msg = ['JobID name not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

        
        function results = redefFormulas(app)
            
            results=[];

            allheights = str2double((app.UITable.Data{:,3}));
            nrows = size(app.UITableLayers.Data,1);
                if all(allheights>0)
                    app.ImpedanceDropDown.Items = { ...
                        '1 - Carson' ...
                        '2 - Noda' ...
                        '3 - Deri' ...
                        '4 - Alvarado-Betancourt' ...
                        '5 - Sunde' ...
                        '6 - Pettersson' ...
                        '7 - Semlyen' ...
                        '8 - Wise (integral form)' ...
                        '11 - Kikuchi' ...
                        };

                    app.AdmittanceDropDown.Items = { ...
                        '1 - Images' ...
                        '2 - Pettersson' ...
                        '3 - Wise (integral form)' ...
                        '6 - Kikuchi' ...
                        };
                    
                    if nrows>0
                        app.ImpedanceDropDown.Items = [app.ImpedanceDropDown.Items '12 - Sunde 2-layered'];
                        app.AdmittanceDropDown.Items = [app.AdmittanceDropDown.Items '7 - Sunde 2-layered'];
                    end

                elseif all(allheights<0)
                    app.ImpedanceDropDown.Items = { ...
                        '9 - Papadopoulos (underground)' ...
                        '13 - Xue (underground)' ...
                    };

                    app.AdmittanceDropDown.Items = { ...
                        '4 - Papadopoulos (underground)' ...
                        '8 - Xue (underground)' ...
                    };

                else
                    app.ImpedanceDropDown.Items = { ...
                        '10 - Mixed (overhead-underground)' ...
                        '14 - Pollaczek (overhead-underground)' ...
                    };

                    app.AdmittanceDropDown.Items = { ...
                        '5 - Mixed (overhead-underground)' ...
                    };


                end
            
        end
        
        function results = writeLineFun(app, fname)
            results=false;
            
            nrows = size(app.UITableLayers.Data,1);
            if nrows > 0
                LayeredData=cell2matButNotStupid(app,app.UITableLayers.Data);
            end


            try

                out{1,1}=    'function [length,Ncon,soil,h,d,Geom]=LineData_fun()';
                out{end+1,1}='%% Line geometry';
                out{end+1,1}='%% 1 column -- number of phase (set to 0 for Kron reduction)';
                out{end+1,1}='%% 2 column -- x position of each conduntor in meters';
                out{end+1,1}='%% 3 column -- y position of each coductor in meters';
                out{end+1,1}='%% 4 column -- internal radii of each conductor';
                out{end+1,1}='%% 5 column -- external radii of each conductor';
                out{end+1,1}='%% 6 column -- resistivity of the conductor';
                out{end+1,1}='%% 7 column -- permeability of the conductor';
                out{end+1,1}='%% 8 column -- external radii of insulation';
                out{end+1,1}='%% 9 column -- relative permeability of insulation';
                out{end+1,1}='%% 10 column -- relative permittivity of insulation';
                out{end+1,1}='%% 11 column -- line length in m';
                out{end+1,1}=sprintf('length = %6.6f;',app.OHLTlengthmEditField.Value);
                out{end+1,1}='Geom=[ ...';
                for k=1:size(app.UITable.Data,1)
                    out{end+1,1}=sprintf('%6.0f  %6.6f  %6.6f  %6.6f  %6.6f  %6.6g  %6.6f  %6.6f  %6.6f  %6.6f  length',app.UITable.Data{k,:});
                end
                out{end+1,1}='];';
                out{end+1,1}='Ncon = size(Geom,1);';
                out{end+1,1}='%% Variables';
                out{end+1,1}='e0=8.854187817e-12;';
                out{end+1,1}='m0=4*pi*1e-7;';
                out{end+1,1}='%% Rearrange data matrix to perform bundle reduction';
                out{end+1,1}='Geom = reorderGeoMatrix(Geom);';
                out{end+1,1}='%% Height of line calculation';
                out{end+1,1}='[h]=height_fun(Ncon,Geom);';
                out{end+1,1}='%% Distances calculations';
                out{end+1,1}='[d]=distance_fun(Ncon,Geom);';
                out{end+1,1}='%% Earth electric parameters';
                out{end+1,1}=sprintf('soil.erg = %6.6f;',app.PermittivitypuEditField.Value);
                out{end+1,1}=sprintf('soil.m_g = m0*%6.6f;',app.PermeabilitypuEditField.Value);
                out{end+1,1}=sprintf('soil.sigma_g = 1/%6.6f;',app.ResistivitymEditField.Value);
                if nrows > 0
                    for i=1:nrows
                        out{end+1,1}=sprintf('soil.layer(%d).erg = %6.6f;',i,LayeredData(i,3));
                        out{end+1,1}=sprintf('soil.layer(%d).m_g = m0*%6.6f;',i,LayeredData(i,2));
                        out{end+1,1}=sprintf('soil.layer(%d).sigma_g = 1/%6.6f;',i,LayeredData(i,1));
                        out{end+1,1}=sprintf('soil.layer(%d).t = %6.6f;',i,LayeredData(i,4));
                    end
                end
                out{end+1,1}=sprintf('end');


                outstr=strjoin(out, '\n') ;
                fid = fopen(fname,'wt');
                fprintf(fid, outstr);
                fclose(fid);
                results=true;

            end
          
        end


        function results = writeMainRunFun(app, fname)
            results=false;

            try
                [Zsrc, Ysrc] = parseZYformula(app);
                currmfile = mfilename('fullpath');
                currPath = replace(currmfile(1:end-length(mfilename())),'\','\\');
                if isempty(app.PythonPathEditField.Value)
                    pycall='python3';
                else
                    pycall=replace(app.PythonPathEditField.Value,'\','\\');
                end
                jobid=app.JobIDEditField.Value;
                

                out{1,1}=    '%% OHLT run';
                out{end+1,1}='%% Project description:';
                out{end+1,1}='%% -------------------';
                out{end+1,1}=sprintf('%%%% %s',strjoin(app.ProjectdescriptionTextArea.Value,'\\n%% '));
                out{end+1,1}='%%';
                out{end+1,1}='close all;';
                out{end+1,1}='clear all;';
                out{end+1,1}='clc;';
                out{end+1,1}='format longEng;';
                out{end+1,1}='%%';
                out{end+1,1}='%% Flags and options';
                out{end+1,1}=sprintf('RunSimu = logical(%d); % Flag to run all the calculations',1);
                out{end+1,1}=sprintf('ZYprnt = logical(%d); % Flag to plot parameters',app.RequestpulparametersplotsCheckBox.Value);
                out{end+1,1}=sprintf('Modprnt = logical(%d); % Flag to plot propagation characteristics',app.RequestmodalparametersplotsCheckBox.Value);
                out{end+1,1}=sprintf('ZYsave = logical(%d); % Flag to save parameters to matfiles',app.SavedatatoMATfilesCheckBox.Value);
                out{end+1,1}=sprintf('export2EMTP = logical(%d); % Flag to export data to a EMTP-compliant matfile',app.EMTPcompliantLineCable_DataMATfileCheckBox.Value);
                % out{end+1,1}=sprintf('export2PCH = logical(%d); % Flag to write data to a PCH JMarti model file',app.ATPcompliantPCHJMartimodelCheckBox.Value);
                out{end+1,1}=sprintf('export2XML = logical(%d); % Flag to write data to a XML ATPDraw file',app.ATPDrawcompliantXMLfileCheckBox.Value);
                out{end+1,1}=sprintf('export2ULM = logical(%d); % Flag to write data to fitULM file (De Conti et al. ATP implementation)',app.ATPTYPE94fitULMmodelDeContietalCheckBox.Value);
                out{end+1,1}=sprintf('FD_flag = %d; % Flag for FD soil models. (0) Constant, (1) Longmire & Smith, (2) Portela, (3) Alipio & Visacro, (4) Datsios & Mikropoulos, (5) Scott, (6) Messier, (7) Visacro & Portela, (8) Visacro & Alipio, (9) Cigre',getFDmodel(app));
                out{end+1,1}=sprintf('decmp_flag = %d; % Modal decomposition flag. (1) QR,(2)QR ATP,(3)simple_QR,(4)simple_QR,(5)NR,(6)NR_back,(7)SQP,(8)SQP_back,(9)LM Levenberg-Marquardt,(10)LM_back,(11)LM_fast,(12)LM_alt, (13) eigenvalue shuffle QR, (14) intercheig QR',getModalTransfmodel(app));
                out{end+1,1}=sprintf('global pythoncall; pythoncall = ''%s'';',pycall);
                out{end+1,1}='%% JobID will be appended to every file or plot generated by this run';
                out{end+1,1}=sprintf('jobid = ''%s'';',jobid);
                out{end+1,1}='%%';
                out{end+1,1}='%% Library functions';
                out{end+1,1}='currmfile = mfilename(''fullpath'');';
                out{end+1,1}='currPath = currmfile(1:end-length(mfilename()));';
                out{end+1,1}=sprintf('WORKDIR=''%s'';',currPath);
                out{end+1,1}=sprintf('addpath([%s ''ZY_OHTL_pul_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''multilayersoil_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''mode_decomp_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''FD_soil_models_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''line_export_funs'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath([%s ''Laplace_sim_model_update'']);','WORKDIR');
                out{end+1,1}=sprintf('addpath(fullfile(%s,''line_export_funs'',''functions''));','WORKDIR');
                out{end+1,1}=sprintf('addpath(fullfile(%s,''line_export_funs'',''vfit3''));','WORKDIR');
                out{end+1,1}='%%';
                out{end+1,1}='%% Frequency range';
                out{end+1,1}=sprintf('f = transpose(logspaceppd(%d,%d,%d));',app.Lowerdec10LHzSpinner.Value,app.Upperdec10UHzSpinner.Value,app.PointsperdecadeSpinner.Value);
                out{end+1,1}='freq_siz=length(f);';
                out{end+1,1}='%%';
                out{end+1,1}='%% Line characteristics';
                out{end+1,1}='[line_length,ord,soil,h,d,Geom]=LineData_fun();';
                out{end+1,1}='%%';
                out{end+1,1}='%% Calculations';
                out{end+1,1}='if RunSimu';
                out{end+1,1}='  tic';
                out{end+1,1}='  [Ztot_Carson,Ztot_Noda,Ztot_Deri,Ztot_AlDe,Ztot_Sunde,Ztot_Pettersson,Ztot_Semlyen,Ztot_Wise,Ztot_under,Ztot_OvUnd,Ztot_Kik,Ztot_Sunde2La,Ztot_OvUndPol,Ztot_Xue,Nph] = ...';
                out{end+1,1}='  Z_clc_fun(f,ord,ZYprnt,FD_flag,freq_siz,soil,h,d,Geom,ZYsave,jobid); %% Calculate Z pul parameters by different earth approaches';
                out{end+1,1}='  %%';
                out{end+1,1}='  [Ytot_Imag,Ytot_Pettersson,Ytot_Wise,Ytot_Papad,Ytot_OvUnd,Ytot_Kik,Ytot_Sunde2La,Ytot_Xue,sigma_g_total,erg_total,Nph] = ...';
                out{end+1,1}='  Y_clc_fun(f,ord,Modprnt,FD_flag,freq_siz,soil,h,d,Geom,ZYsave,jobid); %% Calculate Y pul parameters by different earth approaches';
                out{end+1,1}='  %%';
                out{end+1,1}='  [Zch_mod,Ych_mod,Zch,Ych,g_dis,a_dis,vel_dis,Ti_dis,Z_dis,Y_dis] = ...';
                out{end+1,1}=sprintf('  mode_decomp_fun(%s,%s,f,freq_siz,Nph,decmp_flag,sigma_g_total,erg_total,ZYprnt,jobid); %%%% Modal decomposition',Zsrc,Ysrc);
                out{end+1,1}='  toc';
                out{end+1,1}='  %% Save files';
                out{end+1,1}=sprintf('  Z = %s;',Zsrc);
                out{end+1,1}=sprintf('  Y = %s;',Ysrc);
                out{end+1,1}='  if ZYsave';
                out{end+1,1}=sprintf('      fname = fullfile(currPath,''%s_output.mat'');',jobid);
                out{end+1,1}=sprintf('      save(fname,''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'',''%s'');',...
                    'Z', 'Y', 'Zch_mod', 'Ych_mod', 'Zch', 'Ych', 'g_dis', 'a_dis', 'vel_dis', 'Ti_dis', 'Z_dis', 'Y_dis', 'sigma_g_total', ...
                    'erg_total','f','line_length');
                out{end+1,1}='      FolderName = fullfile(currPath,''plots'');   ';
                out{end+1,1}=sprintf('      mkdir(FolderName)');
                out{end+1,1}='      FigList = findobj(allchild(0), ''flat'', ''Type'', ''figure'');';
                out{end+1,1}='      for iFig = 1:length(FigList)';
                out{end+1,1}='          FigHandle = FigList(iFig);';
                out{end+1,1}='          FigName   = get(FigHandle, ''Name'');';
                out{end+1,1}='          savefig(FigHandle, fullfile(FolderName,[FigName ''.fig'']));';
                out{end+1,1}='      end';
                out{end+1,1}='  end';               
                out{end+1,1}='else';
                out{end+1,1}=sprintf('  load(''%s_output.mat'');',jobid);
                out{end+1,1}='  load(''Z_pul_output.mat'');';
                out{end+1,1}='  load(''Y_pul_output.mat'');';
                out{end+1,1}='end';
                out{end+1,1}='%%';
                out{end+1,1}='%% Export line models';
                % out{end+1,1}=sprintf('BFTOL = %d; %%%% Bodefit tolerance in DB',app.BFTOLdBEditField.Value);
                out{end+1,1}=sprintf('VFopts.errtgt = %6.6f; %%%% Vector-fitting tolerance in pu',app.VFTOLpuEditField.Value);
                out{end+1,1}=sprintf('VFopts.Npoles = [%d %d]; %%%% Number of poles',app.MinPolesEditField.Value,app.MaxPolesEditField.Value);
                %out{end+1,1}='if (export2PCH); makeJMartiModel(f,Z,Y,Ti_dis,g_dis,Zch_mod,line_length,VFTOL,BFTOL,jobid,currPath); end';
                out{end+1,1}='if (export2ULM); makeULModel3(Z,Y,f,line_length,VFopts,jobid,currPath); end';
                out{end+1,1}='if (export2EMTP); punch2emtp(f,Z,Y,line_length,jobid,currPath); end';
                out{end+1,1}='if (export2XML); makeATPXML(f,Z,Y,jobid,currPath); end';
                outstr=strjoin(out, '\n') ;
                fid = fopen(fname,'wt');
                fprintf(fid, outstr);
                fclose(fid);
                results=true;

            end
          
        end

        
        function [outZ, outY] = parseZYformula(app)

            Zf=app.ImpedanceDropDown.Value;
            idcs=strfind(Zf,'-');
            Zf=str2num(Zf(1:idcs(1)-1));

            Yf=app.AdmittanceDropDown.Value;
            idcs=strfind(Yf,'-');
            Yf=str2num(Yf(1:idcs(1)-1));
            
            switch Zf
                case 1
                    outZ='Ztot_Carson';
                case 2
                    outZ='Ztot_Noda';
                case 3
                    outZ='Ztot_Deri';
                case 4
                    outZ='Ztot_AlDe';
                case 5
                    outZ='Ztot_Sunde';
                case 6
                    outZ='Ztot_Pettersson';
                case 7
                    outZ='Ztot_Semlyen';
                case 8
                    outZ='Ztot_Wise';
                case 9
                    outZ='Ztot_under';
                case 10
                    outZ='Ztot_OvUnd';
                case 11
                    outZ='Ztot_Kik';
                case 12
                    outZ='Ztot_Sunde2La';
                case 13
                    outZ='Ztot_Xue';
                case 14
                    outZ='Ztot_OvUndPol';
            end

            switch Yf
                case 1
                    outY='Ytot_Imag';
                case 2
                    outY='Ytot_Pettersson';
                case 3
                    outY='Ytot_Wise';
                case 4
                    outY='Ytot_Papad';
                case 5
                    outY='Ytot_OvUnd';
                case 6
                    outY='Ytot_Kik';
                case 7
                    outY='Ytot_Sunde2La';
                case 8
                    outY='Ytot_Xue';
            end

        end
        
        function out = getFDmodel(app)
            choice=app.FDsoilDropDown.Value;
            idcs=strfind(choice,'-');
            out=str2num(choice(1:idcs(1)-1));
        end

        function out = getModalTransfmodel(app)
            choice=app.ModaldecompositionflagDropDown.Value;
            idcs=strfind(choice,'-');
            out=str2num(choice(1:idcs(1)-1));
        end

        
        function tableDataNumeric = cell2matButNotStupid(app, tableData)
            tableDataNumeric=[];

            % Check for empty table
            if isempty(tableData)
                return; % Bail out, nothing to validate here
            end

            % Convert cell array to numeric array, setting NaN for empty cells
            tableDataNumeric = NaN(size(tableData));
            for i = 1:numel(tableData)
                if ~isempty(tableData{i})
                    tableDataNumeric(i) = tableData{i};
                end
            end

        end

    end %this is the end of the private methods section

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);
            t=table;
            t.PhaseNum=strings(0);
            t.Horiz=strings(0);
            t.Vert=strings(0);
            t.IntRadius=strings(0);
            t.ExtRadius=strings(0);
            t.CondResist=strings(0);
            t.CondPerm=strings(0);
            t.InsulRadius=strings(0);
            t.InsulPerm=strings(0);
            t.InsulPermit=strings(0);
            app.UITable.Data=t;
            app.UITable.ColumnEditable = [true true true true true true true true true true];
%             app.UITable.ColumnWidth = {115,60,100,'auto'};
            

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
        
            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            nrows = size(app.UITableLayers.Data,1);
            if nrows > 0
                LayeredData=cell2matButNotStupid(app,app.UITableLayers.Data);
                if any(isnan(LayeredData))
                    msg = 'The joys of creative input are lovely, but the laws of this universe impose that soil resistivity data must be all numeric.'
                    f = msgbox(msg, 'You lose, fella.','error');
                    return
                end
            end

            %ok now do stuff
            jobid=app.JobIDEditField.Value;

            [testdir,msg]=validateWorkdir(app);
            if ~testdir
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            workdir=app.WorkdirectoryEditField.Value;
            orig_state = warning;warning('off','all');
            answer='Yes';

            if isfolder(fullfile(workdir,jobid))
                allfiles=dir(fullfile(workdir,jobid,'*.*'));
                

                if ~isempty(allfiles)
                    answer = questdlg('Warning: files were found in the target directory and will be deleted. Confirm?', ...
                        '\WARNING\WARNING\WARNING\WARNING\WARNING\', ...
                        'Yes','No','Yes');
                    if strcmp(answer,'No')
                        return;
                    else
                        rmdir(fullfile(workdir,jobid), 's');
                    end
                end

            end
            
            mkdir(fullfile(workdir,jobid));
            warning(orig_state);
            fname=fullfile(workdir,jobid,'LineData_fun.m');
            test1 = writeLineFun(app,fname);
            if ~test1
                msg = 'Unable to write the line data function!';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=fullfile(workdir,jobid,['RunJob_' jobid '.m']);
            test2 = writeMainRunFun(app,fname);
            if ~test2
                msg = 'Unable to write the main function wrapper!';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            [folder,name,ext]=fileparts(fname);
            if test1 && test2 && strcmp(answer,'Yes')
                msg = sprintf('Project files created sucessfully! You may now open the ''%s'' folder and invoke the ''%s.m'' script to unleash this big boy!',jobid,name);
                f = msgbox(msg, 'Success!','help');
                return;
            end


        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={9999,9999,9999,9999,9999,9999,9999,9999,9999,9999};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app);
            redefFormulas(app);
            RefreshPreviewButtonPushed(app, event);


        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            delrow=str2double(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app);
                redefFormulas(app);
                RefreshPreviewButtonPushed(app, event);
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=double(selectedRow);
                end

            end
        end

        % Button pushed function: SaveinputsessionButton
        function SaveinputsessionButtonPushed(app, event)
            saveAllData(app);
        end

        % Button pushed function: LoadinputsessionButton
        function LoadinputsessionButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'ohlt_data');
                    writeAllAppValues(app,ohlt_data);
                    %                     setColFormats(app);
%                     redefFormulas(app);
                    RefreshPreviewButtonPushed(app, event);
                catch
                    f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            try
                test=isscalar((eval(newData)));
                redefFormulas(app);

                RefreshPreviewButtonPushed(app, event);


            catch
                f = msgbox('Invalid data or MATLAB operator.','You lose, fella.','error');
                return;
            end
            
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: RefreshPreview
        function RefreshPreviewButtonPushed(app, event)
            
            try
                plotdata=table2array(app.UITable.Data(:,2:3));
                plotdata=str2double(plotdata);
                scatter(app.UIAxes, plotdata(:,1),plotdata(:,2));
                %app.UIAxes.YLim = [0.9*min(plotdata(:,2)) 1.1*max(plotdata(:,2))];
            end
        end

        % Button pushed function: LoadPythonPathButton
        function LoadPythonPathButtonPushed(app, event)
            [file,path]=uigetfile(pwd,'Choose Python binary');
            app.mainform.Visible = 'off';
            app.mainform.Visible = 'on';
            if path ~=0
                app.PythonPathEditField.Value=fullfile(path,file);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            MAXLAYERS=1;
            nrows = size(app.UITableLayers.Data,1);
            if nrows < MAXLAYERS
                addNewLayer(app);
            else
            f = msgbox('Currently only models up to two layers are supported (1 top layer + 1 uniform layer).','You lose, fella.','error');
                return;
            end
            redefFormulas(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            delrow=str2double(app.Layer2Delete.Value);
            if ~isempty(app.UITableLayers.Data)
                if isnan(delrow)
                    app.UITableLayers.Data(end,:)=[];
                else
                    app.UITableLayers.Data(delrow,:)=[];
                end
                redefLayerLabels(app);
                redefFormulas(app);
            end
        end

        % Cell selection callback: UITableLayers
        function UITableLayersCellSelection(app, event)
            if ~isempty(app.UITableLayers.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.Layer2Delete.Value=double(selectedRow);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.mainform = uifigure('Visible', 'off');
            app.mainform.Name = 'LineCableLab';
            app.mainform.Icon = fullfile(pathToMLAPP, 'appicon.png');
            app.TabGroup = uitabgroup(app.mainform);
            app.CasedataTab = uitab(app.TabGroup);
            app.CasedataTab.Title = 'Case data';
            app.UIAxes = uiaxes(app.CasedataTab);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'Horizontal [m]')
            ylabel(app.UIAxes, 'Vertical [m]')
            zlabel(app.UIAxes, 'Z')
            app.OHLcrosssectionconductordataPanel = uipanel(app.CasedataTab);
            app.OHLcrosssectionconductordataPanel.Title = 'OHL cross section & conductor data';
            app.UITable = uitable(app.OHLcrosssectionconductordataPanel);
            app.UITable.ColumnName = {'PhaseNum'; 'Horiz [m]'; 'Vert [m]'; 'IntRadius [m]'; 'ExtRadius [m]'; 'CondResist [Ωm]'; 'CondPerm [pu]'; 'InsulRadius [m]'; 'InsulPerm [pu]'; 'InsulPermit [pu]'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddrowButton = uibutton(app.OHLcrosssectionconductordataPanel, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleterowButton = uibutton(app.OHLcrosssectionconductordataPanel, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = '🗙 Delete row';
            app.NotesetPhaseNum0toenforceKroneliminationLabel = uilabel(app.OHLcrosssectionconductordataPanel);
            app.NotesetPhaseNum0toenforceKroneliminationLabel.Text = {'Note: set PhaseNum=0 to enforce Kron elimination; '; 'use the same PhaseNum for bundled conductors.'};
            app.CurrentrowLabel = uilabel(app.OHLcrosssectionconductordataPanel);
            app.CurrentrowLabel.Text = 'Current row:';
            app.row2Delete = uieditfield(app.OHLcrosssectionconductordataPanel, 'numeric');
            app.row2Delete.ValueDisplayFormat = '%.0f';
            app.row2Delete.Editable = 'off';
            app.OHLTlengthmEditFieldLabel = uilabel(app.OHLcrosssectionconductordataPanel);
            app.OHLTlengthmEditFieldLabel.Text = 'OHLT length [m]';
            app.OHLTlengthmEditField = uieditfield(app.OHLcrosssectionconductordataPanel, 'numeric');
            app.OHLTlengthmEditField.Value = 1000;
            app.GeneralPanel = uipanel(app.CasedataTab);
            app.GeneralPanel.Title = 'General';
            app.LoadWorkdirButton = uibutton(app.GeneralPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.WorkdirectoryEditFieldLabel = uilabel(app.GeneralPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory';
            app.WorkdirectoryEditField = uieditfield(app.GeneralPanel, 'text');
            app.JobIDLabel = uilabel(app.GeneralPanel);
            app.JobIDLabel.Text = 'JobID';
            app.JobIDEditField = uieditfield(app.GeneralPanel, 'text');
            app.ProjectdescriptionLabel = uilabel(app.GeneralPanel);
            app.ProjectdescriptionLabel.Text = 'Project description';
            app.ProjectdescriptionTextArea = uitextarea(app.GeneralPanel);
            app.RefreshPreview = uibutton(app.CasedataTab, 'push');
            app.RefreshPreview.ButtonPushedFcn = createCallbackFcn(app, @RefreshPreviewButtonPushed, true);
            app.RefreshPreview.Text = '↻';
            app.ComputationsTab = uitab(app.TabGroup);
            app.ComputationsTab.Title = 'Computations';
            app.GroundreturnmodelPanel = uipanel(app.ComputationsTab);
            app.GroundreturnmodelPanel.Title = 'Ground return model';
            app.ImpedanceDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.ImpedanceDropDownLabel.Text = 'Impedance';
            app.ImpedanceDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.ImpedanceDropDown.Items = {'1 - Carson', '2 - Noda', '3 - Deri', '4 - Alvarado-Betancourt', '5 - Sunde', '6 - Pettersson', '7 - Semlyen', '8 - Wise (integral form)', '9 - Papadopoulos (underground)', '10 - Mixed (overhead-underground)', '11 - Kikuchi', '12 - Sunde 2-layered', '13 - Xue (underground)', '14 - Pollaczek (overhead-underground)'};
            app.ImpedanceDropDown.Editable = 'on';
            app.ImpedanceDropDown.Value = '1 - Carson';
            app.AdmittanceDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.AdmittanceDropDownLabel.Text = 'Admittance';
            app.AdmittanceDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.AdmittanceDropDown.Items = {'1 - Images', '2 - Pettersson', '3 - Wise (integral form)', '4 - Papadopoulos (underground)', '5 - Mixed (overhead-underground)', '6 - Kikuchi', '7 - Sunde 2-layered', '8 - Xue (underground)'};
            app.AdmittanceDropDown.Editable = 'on';
            app.AdmittanceDropDown.Value = '1 - Images';
            app.FDsoilDropDownLabel = uilabel(app.GroundreturnmodelPanel);
            app.FDsoilDropDownLabel.Text = 'FD soil ';
            app.FDsoilDropDown = uidropdown(app.GroundreturnmodelPanel);
            app.FDsoilDropDown.Items = {'0 - Constant', '1 - Longmire & Smith', '2 - Portela', '3 - Alipio & Visacro', '4 - Datsios & Mikropoulos', '5 - Scott', '6 - Messier', '7 - Visacro & Portela', '8 - Visacro & Alipio', '9 - Cigré'};
            app.FDsoilDropDown.Editable = 'on';
            app.FDsoilDropDown.Value = '0 - Constant';
            app.ModaltransformPanel = uipanel(app.ComputationsTab);
            app.ModaltransformPanel.Title = 'Modal transform';
            app.ModaltransformmatrixDropDownLabel = uilabel(app.ModaltransformPanel);
            app.ModaltransformmatrixDropDownLabel.Text = 'Modal transform matrix';
            app.ModaltransformmatrixDropDown = uidropdown(app.ModaltransformPanel);
            app.ModaltransformmatrixDropDown.Items = {'Wedehpol', 'Fortescue (IN PROGRESS)', 'Clarke (IN PROGRESS)'};
            app.ModaltransformmatrixDropDown.Enable = 'off';
            app.ModaltransformmatrixDropDown.Value = 'Wedehpol';
            app.ModaldecompositionflagDropDownLabel = uilabel(app.ModaltransformPanel);
            app.ModaldecompositionflagDropDownLabel.Text = 'Modal decomposition flag';
            app.ModaldecompositionflagDropDown = uidropdown(app.ModaltransformPanel);
            app.ModaldecompositionflagDropDown.Items = {'1 - QR', '2 - QR ATP', '3 - simple_QR', '4 - simple_QR', '5 - NR', '6 - NR_back', '7 - SQP', '8 - SQP_back', '9 - LM (Levenberg-Marquardt)', '10 - LM_back', '11 - LM_fast', '12 - LM_alt', '13 - QR eigenvalue shuffle', '14 - QR intercheig'};
            app.ModaldecompositionflagDropDown.Editable = 'on';
            app.ModaldecompositionflagDropDown.Value = '1 - QR';
            app.FrequencysweeplogspacedpointsPanel = uipanel(app.ComputationsTab);
            app.FrequencysweeplogspacedpointsPanel.Title = 'Frequency sweep (logspaced points)';
            app.Upperdec10UHzSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.Upperdec10UHzSpinnerLabel.Text = 'Upper dec. [10^U Hz]';
            app.Upperdec10UHzSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.Upperdec10UHzSpinner.Limits = [-10 Inf];
            app.Upperdec10UHzSpinner.ValueDisplayFormat = '%d';
            app.Upperdec10UHzSpinner.Value = 6;
            app.PointsperdecadeSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.PointsperdecadeSpinnerLabel.Text = 'Points per decade';
            app.PointsperdecadeSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.PointsperdecadeSpinner.Limits = [2 Inf];
            app.PointsperdecadeSpinner.ValueDisplayFormat = '%d';
            app.PointsperdecadeSpinner.Value = 10;
            app.Lowerdec10LHzSpinnerLabel = uilabel(app.FrequencysweeplogspacedpointsPanel);
            app.Lowerdec10LHzSpinnerLabel.Text = 'Lower dec. [10^L Hz]';
            app.Lowerdec10LHzSpinner = uispinner(app.FrequencysweeplogspacedpointsPanel);
            app.Lowerdec10LHzSpinner.Limits = [-10 Inf];
            app.Lowerdec10LHzSpinner.ValueDisplayFormat = '%d';
            app.Lowerdec10LHzSpinner.Value = -1;
            app.AddButton = uibutton(app.ComputationsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = '➕ Add';
            app.DeleteButton = uibutton(app.ComputationsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = '🗙 Delete';
            app.UITableLayers = uitable(app.ComputationsTab);
            app.UITableLayers.ColumnName = {'Resistivity [Ωm]'; 'Permeability [pu]'; 'Permittivity [pu]'; 'Thickness [m]'};
            app.UITableLayers.RowName = {};
            app.UITableLayers.ColumnEditable = true;
            app.UITableLayers.CellSelectionCallback = createCallbackFcn(app, @UITableLayersCellSelection, true);
            app.UITableLayers.Enable = 'off';
            app.PlotsavePanel = uipanel(app.ComputationsTab);
            app.PlotsavePanel.Title = 'Plot & save';
            app.RequestmodalparametersplotsCheckBox = uicheckbox(app.PlotsavePanel);
            app.RequestmodalparametersplotsCheckBox.Text = 'Request modal parameters plots';
            app.RequestmodalparametersplotsCheckBox.Value = true;
            app.SavedatatoMATfilesCheckBox = uicheckbox(app.PlotsavePanel);
            app.SavedatatoMATfilesCheckBox.Text = 'Save data to MAT-files';
            app.SavedatatoMATfilesCheckBox.Value = true;
            app.RequestpulparametersplotsCheckBox = uicheckbox(app.PlotsavePanel);
            app.RequestpulparametersplotsCheckBox.Text = 'Request pul parameters plots';
            app.RequestpulparametersplotsCheckBox.Value = true;
            app.MiscPanel = uipanel(app.ComputationsTab);
            app.MiscPanel.Title = 'Misc';
            app.LoadPythonPathButton = uibutton(app.MiscPanel, 'push');
            app.LoadPythonPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPythonPathButtonPushed, true);
            app.LoadPythonPathButton.Text = '📂';
            app.PythonPathEditFieldLabel = uilabel(app.MiscPanel);
            app.PythonPathEditFieldLabel.Text = 'Python interpreter';
            app.PythonPathEditField = uieditfield(app.MiscPanel, 'text');
            app.LoadExtFileButton = uibutton(app.MiscPanel, 'push');
            app.LoadExtFileButton.Enable = 'off';
            app.LoadExtFileButton.Visible = 'off';
            app.LoadExtFileButton.Text = '📂';
            app.ExtFileEditFieldLabel = uilabel(app.MiscPanel);
            app.ExtFileEditFieldLabel.Enable = 'off';
            app.ExtFileEditFieldLabel.Visible = 'off';
            app.ExtFileEditFieldLabel.Text = 'ZY source file';
            app.ExtFileEditField = uieditfield(app.MiscPanel, 'text');
            app.ExtFileEditField.Enable = 'off';
            app.ExtFileEditField.Visible = 'off';
            app.ExtFileEditField.Value = 'Coming soon.';
            app.NotesetPhaseNum0toenforceKroneliminationLabel_2 = uilabel(app.MiscPanel);
            app.NotesetPhaseNum0toenforceKroneliminationLabel_2.Text = {'Note: this is needed only if tubular conductors are present'; 'and numerical instabilities occur. '};
            app.TopsoillayersLabel = uilabel(app.ComputationsTab);
            app.TopsoillayersLabel.Text = 'Top soil layers:';
            app.CurrentrowLabel_2 = uilabel(app.ComputationsTab);
            app.CurrentrowLabel_2.Text = 'Current layer:';
            app.Layer2Delete = uieditfield(app.ComputationsTab, 'numeric');
            app.Layer2Delete.ValueDisplayFormat = '%.0f';
            app.Layer2Delete.Editable = 'off';
            app.UniformbottomsoillayerPanel = uipanel(app.ComputationsTab);
            app.UniformbottomsoillayerPanel.Title = 'Uniform (bottom) soil layer';
            app.GridLayout = uigridlayout(app.UniformbottomsoillayerPanel);
            app.ResistivitymEditFieldLabel = uilabel(app.GridLayout);
            app.ResistivitymEditFieldLabel.Text = 'Resistivity [Ωm]';
            app.ResistivitymEditField = uieditfield(app.GridLayout, 'numeric');
            app.ResistivitymEditField.Value = 100;
            app.PermeabilitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermeabilitypuEditFieldLabel.Text = 'Permeability [pu]';
            app.PermeabilitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermeabilitypuEditField.Value = 1;
            app.PermittivitypuEditFieldLabel = uilabel(app.GridLayout);
            app.PermittivitypuEditFieldLabel.Text = 'Permittivity [pu]';
            app.PermittivitypuEditField = uieditfield(app.GridLayout, 'numeric');
            app.PermittivitypuEditField.Value = 1;
            app.ExportlinemodelPanel = uipanel(app.ComputationsTab);
            app.ExportlinemodelPanel.Title = 'Export line model';
            app.EMTPcompliantLineCable_DataMATfileCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.EMTPcompliantLineCable_DataMATfileCheckBox.Text = ' EMTP-compliant LineCable_Data  MAT-file';
            app.ATPcompliantPCHJMartimodelCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPcompliantPCHJMartimodelCheckBox.Enable = 'off';
            app.ATPcompliantPCHJMartimodelCheckBox.Text = 'ATP-compliant PCH JMarti model';
            app.ATPTYPE94fitULMmodelDeContietalCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPTYPE94fitULMmodelDeContietalCheckBox.Text = 'ATP TYPE-94 fitULM model (De Conti et al.)';
            app.ATPDrawcompliantXMLfileCheckBox = uicheckbox(app.ExportlinemodelPanel);
            app.ATPDrawcompliantXMLfileCheckBox.Text = 'ATPDraw-compliant XML file';
            app.FittingoptionsPanel = uipanel(app.ComputationsTab);
            app.FittingoptionsPanel.Title = 'Fitting options';
            app.MaxPolesEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.MaxPolesEditFieldLabel.Text = 'MaxPoles';
            app.MaxPolesEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.MaxPolesEditField.Limits = [0 Inf];
            app.MaxPolesEditField.RoundFractionalValues = 'on';
            app.MaxPolesEditField.ValueDisplayFormat = '%d';
            app.MaxPolesEditField.Value = 20;
            app.BFTOLdBEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.BFTOLdBEditFieldLabel.Enable = 'off';
            app.BFTOLdBEditFieldLabel.Text = 'BFTOL [dB]';
            app.BFTOLdBEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.BFTOLdBEditField.Limits = [0 Inf];
            app.BFTOLdBEditField.RoundFractionalValues = 'on';
            app.BFTOLdBEditField.ValueDisplayFormat = '%d';
            app.BFTOLdBEditField.Enable = 'off';
            app.BFTOLdBEditField.Value = 3;
            app.VFTOLLabel = uilabel(app.FittingoptionsPanel);
            app.VFTOLLabel.Text = 'VFTOL [pu]';
            app.VFTOLpuEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.VFTOLpuEditField.Limits = [0 1];
            app.VFTOLpuEditField.Value = 0.01;
            app.MinPolesEditFieldLabel = uilabel(app.FittingoptionsPanel);
            app.MinPolesEditFieldLabel.Text = 'MinPoles';
            app.MinPolesEditField = uieditfield(app.FittingoptionsPanel, 'numeric');
            app.MinPolesEditField.Limits = [2 Inf];
            app.MinPolesEditField.RoundFractionalValues = 'on';
            app.MinPolesEditField.ValueDisplayFormat = '%d';
            app.MinPolesEditField.Value = 2;
            app.SaveinputsessionButton = uibutton(app.mainform, 'push');
            app.SaveinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsessionButtonPushed, true);
            app.SaveinputsessionButton.Text = '🖪 Save input session';
            app.LoadinputsessionButton = uibutton(app.mainform, 'push');
            app.LoadinputsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinputsessionButtonPushed, true);
            app.LoadinputsessionButton.Text = '📁 Load input session';
            app.ExitButton = uibutton(app.mainform, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '🚪 Exit';
            app.StartButton = uibutton(app.mainform, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '▶️ Start!';
            app.ConductordatabaseButton = uibutton(app.mainform, 'push');
            app.ConductordatabaseButton.Enable = 'off';
            app.ConductordatabaseButton.Visible = 'off';
            app.ConductordatabaseButton.Text = '🗃️ Conductor database';
            % Show the figure after all components are created
            app.mainform.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linecablelab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainform)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainform)
        end
    end
end